classdef APP_Cartesiano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        InterfazScaraButton        matlab.ui.control.Button
        PosicionActualPanel        matlab.ui.container.Panel
        PosZ                       matlab.ui.control.EditField
        PosY                       matlab.ui.control.EditField
        PosX                       matlab.ui.control.EditField
        Limpiar                    matlab.ui.control.Button
        text7_2                    matlab.ui.control.Label
        text6_2                    matlab.ui.control.Label
        text5_2                    matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        CinamicaDirectaTab         matlab.ui.container.Tab
        ManualPanel                matlab.ui.container.Panel
        Boton1                     matlab.ui.control.Button
        text16                     matlab.ui.control.Label
        text15                     matlab.ui.control.Label
        text14                     matlab.ui.control.Label
        D3                         matlab.ui.control.EditField
        D2                         matlab.ui.control.EditField
        D1                         matlab.ui.control.EditField
        SlidersPanel               matlab.ui.container.Panel
        text13                     matlab.ui.control.Label
        text12                     matlab.ui.control.Label
        text11                     matlab.ui.control.Label
        text10                     matlab.ui.control.Label
        text9                      matlab.ui.control.Label
        text8                      matlab.ui.control.Label
        Boton3                     matlab.ui.control.Button
        C                          matlab.ui.control.EditField
        B                          matlab.ui.control.EditField
        A                          matlab.ui.control.EditField
        text4                      matlab.ui.control.Label
        Slider3                    matlab.ui.control.Slider
        Slider2                    matlab.ui.control.Slider
        text3                      matlab.ui.control.Label
        Teta1                      matlab.ui.control.Label
        Slider1                    matlab.ui.control.Slider
        CinematicainversaTab       matlab.ui.container.Tab
        BotonEF                    matlab.ui.control.Button
        DistanciasObtenidasPanel   matlab.ui.container.Panel
        text30                     matlab.ui.control.Label
        edit21                     matlab.ui.control.EditField
        text29                     matlab.ui.control.Label
        text28                     matlab.ui.control.Label
        edit20                     matlab.ui.control.EditField
        edit19                     matlab.ui.control.EditField
        PosicionEfectorFinalPanel  matlab.ui.container.Panel
        EfectorZ                   matlab.ui.control.EditField
        EfectorY                   matlab.ui.control.EditField
        EfectorX                   matlab.ui.control.EditField
        text24                     matlab.ui.control.Label
        text23                     matlab.ui.control.Label
        text22                     matlab.ui.control.Label
        Grafica3DTab               matlab.ui.container.Tab
        graficarLinks              matlab.ui.control.Button
        Graficar_3d                matlab.ui.control.Button
        ConfiguracionesPanel       matlab.ui.container.Panel
        VelMovimientoLabel         matlab.ui.control.Label
        PasosLabel                 matlab.ui.control.Label
        vel_3d                     matlab.ui.control.EditField
        pasos_3d                   matlab.ui.control.EditField
        PosicionPanel              matlab.ui.container.Panel
        ZfLabel                    matlab.ui.control.Label
        YfLabel                    matlab.ui.control.Label
        XfLabel                    matlab.ui.control.Label
        yf_3d                      matlab.ui.control.EditField
        zf_3d                      matlab.ui.control.EditField
        xf_3d                      matlab.ui.control.EditField
        text31_2                   matlab.ui.control.Label
        ZoLabel                    matlab.ui.control.Label
        YoLabel                    matlab.ui.control.Label
        XoLabel                    matlab.ui.control.Label
        y0_3d                      matlab.ui.control.EditField
        z0_3d                      matlab.ui.control.EditField
        x0_3d                      matlab.ui.control.EditField
        text31                     matlab.ui.control.Label
        TablaDH                    matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        A01Tab                     matlab.ui.container.Tab
        Tabla1                     matlab.ui.control.Table
        text17_2                   matlab.ui.control.Label
        A12Tab                     matlab.ui.container.Tab
        Tabla2                     matlab.ui.control.Table
        text18_2                   matlab.ui.control.Label
        A23Tab                     matlab.ui.container.Tab
        Tabla3                     matlab.ui.control.Table
        text19_2                   matlab.ui.control.Label
        ATTab                      matlab.ui.container.Tab
        TablaT                     matlab.ui.control.Table
        text20_2                   matlab.ui.control.Label
        PerfilCuadraticoTab        matlab.ui.container.Tab
        PosicionButton             matlab.ui.control.Button
        AceleracionButton          matlab.ui.control.Button
        VelocidadButton            matlab.ui.control.Button
        PorfavorprimerorealiceCinematicaInversaLabel  matlab.ui.control.Label
        ConfiguracionesPanel_2     matlab.ui.container.Panel
        TiempoFinalLabel           matlab.ui.control.Label
        PasosLabel_2               matlab.ui.control.Label
        tf                         matlab.ui.control.EditField
        bt_n                       matlab.ui.control.EditField
        VelocidadPanel             matlab.ui.container.Panel
        VeIn3Label                 matlab.ui.control.Label
        VeIn2Label                 matlab.ui.control.Label
        VeIn1Label                 matlab.ui.control.Label
        VeFi3Label                 matlab.ui.control.Label
        VeFi2Label                 matlab.ui.control.Label
        VeFi1Label                 matlab.ui.control.Label
        Vel_fin2                   matlab.ui.control.EditField
        Vel_fin3                   matlab.ui.control.EditField
        Vel_fin1                   matlab.ui.control.EditField
        text31_4                   matlab.ui.control.Label
        text31_3                   matlab.ui.control.Label
        Vel_in2                    matlab.ui.control.EditField
        Vel_in3                    matlab.ui.control.EditField
        Vel_in1                    matlab.ui.control.EditField
        CalcularButton             matlab.ui.control.Button
        text21                     matlab.ui.control.Label
        axes1                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vect  %Vector de posicion
        vecta %Vector de aceleracion
        vectv %Vector de velocidad
        n     %Resolucion
        tfgraf % Description
    end
    
    methods (Access = private)
        function Boton2_Callback(~, ~, eventdata, handles)
            % hObject    handle to Boton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PX = str2double(handles.PosX.String);
            PY = str2double(handles.PosY.String);
            PZ = str2double(handles.PosZ.String);
            
            L_1 = 150;
            L_2 = 183;
            L_3 = 148;
            
            %            teta     d      a        alfa
            L(1) = Link([ 0      L_1     0      pi/2]);
            L(2) = Link([ 0       0     L_2       0]);
            L(3) = Link([ 0       0      L_3      0]);
            L(4) = Link([0 0 L_4 -pi/2]);
            L(5) = Link([0 0 L_5 pi/2]);
            L(6) = Link([0 L_6 0 0]);
            
            
            Robot = SerialLink(L);
            Robot.name = 'Six_R_Robot';
            
            T = [ 1 0 0 PX;
                  0 1 0 PY;
                  0 0 1 PZ;
                  0 0 0 1];
            
            J = Robot.ikine(T,[0 0 0],'mask',[1 1 1 0 0 0])*180/pi;
            handles.D1.String = num2str(floor(J(1)));
            handles.D2.String = num2str(floor(J(2)));
            handles.D3.String = num2str(floor(J(3)));
            handles.Theta4.String = num2str(floor(J(3)));
            handles.Theta5.String = num2str(floor(J(3)));
            handles.Theta5.String = num2str(floor(J(3)));
            
            Robot.plot(J*pi/180);
        end
        
        function Theta4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Theta4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Theta5_CreateFcn(~, hObject, ~, handles)
            % hObject    handle to Theta5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Theta6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Theta6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.Tabla2.ColumnFormat = {[] [] [] []};
            app.Tabla3.ColumnFormat = {[] [] [] []};
            app.TablaT.ColumnFormat = {[] [] [] []};
            app.Tabla1.BackgroundColor = [1 1 1];
            app.Tabla1.ColumnFormat = {[] [] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI (see VARARGIN)
            
            handles.output = hObject;
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            % Update handles structure
            guidata(hObject, handles);
            
            A01 = [ 1     0         0       0;
                    0  6.12e-17    -1       0;
                    0     1      6.12e-17    0;
                    0     0         0      1];
            
            A12 = [6.12e-17     -6.12e-17            1        0;
                      1          3.75e-33        -6.12e-17    0 ;
                      0             1             6.12e-17    0 ;
                      0             0                0        1 ];
            
            A23 = [1 0 0 0;
                   0 1 0 0 ;
                   0 0 1 0;
                   0 0 0 1];
            set(handles.Tabla1,'data',A01);
            set(handles.Tabla2,'data',A12);
            set(handles.Tabla3,'data',A23);
            MATRIZ_TRANSFORMACION = A01*A12*A23;
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
            
%             A_Callback(handles.A,[],handles);
%             B_Callback(handles.B,[],handles);
%             C_Callback(handles.C,[],handles);
            
            
            % Choose default command line output for GUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Boton1
        function Boton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Boton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            d_1= str2double(handles.D1.String);
            d_2= str2double(handles.D2.String);
            d_3 = str2double(handles.D3.String);
            
            L1 = Prismatic( 'a',0,'alpha',pi/2,'theta',0,'qlim',[0,25]);
            L2 = Prismatic( 'a',0,'alpha',pi/2,'theta',pi/2,'qlim',[0,25]);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Cartesiano';
            ws=[-150,150,-150,150,0,200]
            Robot.plot([d_1,d_2, d_3],'workspace',ws,'noname');
            
            T = Robot.fkine([d_1 d_2 d_3]);
            handles.PosX.String = num2str(floor(T.t(1)));
            handles.PosY.String = num2str(-floor(T.t(2)));
            handles.PosZ.String = num2str(floor(T.t(3)));
        end

        % Button pushed function: Boton3
        function Boton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Boton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            d1 = get(handles.Slider1,'value');
            d2 = get(handles.Slider2,'value');
            d3 = get(handles.Slider3,'value');
            
            t1b3 = d1;
            t2b3 = d2;
            t3b3 = d3;
            
            L1 = Prismatic( 'a',0,'alpha',pi/2,'theta',0,'qlim',[0,25]);
            L2 = Prismatic( 'a',0,'alpha',pi/2,'theta',pi/2,'qlim',[0,25]);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Cartesiano';
            ws=[-150,150,-150,150,0,200];
            Robot.plot([t1b3, t2b3, t3b3],'workspace',ws,'noname');
            
            T = Robot.fkine([t1b3 t2b3 t3b3]);
            handles.PosX.String = num2str(floor(T.t(1)));
            handles.PosY.String = num2str(-floor(T.t(2)));
            handles.PosZ.String = num2str(floor(T.t(3)));
        end

        % Button pushed function: BotonEF
        function BotonEF_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BotonEF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Px = str2double(handles.EfectorX.String);
            Py = str2double(handles.EfectorY.String);
            Pz = str2double(handles.EfectorZ.String);
            
            %             teta       d       a      alfa
            L1 = Prismatic( 'a',0,'alpha',pi/2,'theta',0,'qlim',[0,25]);
            L2 = Prismatic( 'a',0,'alpha',pi/2,'theta',pi/2,'qlim',[0,25]);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            d1e=Pz;
            d2e=Py;
            d3e=Px
            
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Cartesiano';
            ws=[-150,150,-150,150,0,200]
            
            P=[Px,Py,Pz]
            
            Robot.plot([d1e,d2e,d3e],'workspace',ws,'noname');
            % Robot.plot([d_1,d_2, d_3],'workspace',ws,'noname');
            
            handles.edit19.String = num2str(floor(abs(Px)));
            handles.edit20.String = num2str(floor(abs(Py)));
            handles.edit21.String = num2str(floor(abs(Pz)));
            
            handles.PosX.String = num2str(floor(Px));
            handles.PosY.String = num2str(-floor(Py));
            handles.PosZ.String = num2str(floor(Pz));
        end

        % Button pushed function: Graficar_3d
        function Graficar_3d_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Graficar_3d (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
          
            Pxf = str2double(app.xf_3d.Value);
            Pyf = str2double(app.yf_3d.Value);
            Pzf = str2double(app.zf_3d.Value);
            
            Px0 = str2double(app.x0_3d.Value);
            Py0 = str2double(app.y0_3d.Value);
            Pz0 = str2double(app.z0_3d.Value);
            
            %             teta       d       a      alfa
            L1 = Prismatic( 'a',0,'alpha',pi/2,'theta',0,'qlim',[0,25]);
            L2 = Prismatic( 'a',0,'alpha',pi/2,'theta',pi/2,'qlim',[0,25]);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Cartesiano';
            ws=[-150,150,-150,150,0,200]
            
            n=3;
            syms t1 t2 t4 d3 a1 a2
            d_1 = 150;
            d_2 = 183;
            d_3 = 148;
            
            theta=[0 90 0];
            alfa=[90 90 0];
            dis=[d_1 d_2 d_3];
            a=[0 0 0];
            Tf=1;
            
            for i=1:n
                Tx=[cosd(theta(i)) -sind(theta(i))*cosd(alfa(i)) sind(theta(i))*(sind(alfa(i)))  a(i)*cosd(theta(i));
                    sind(theta(i))  cosd(theta(i))*cosd(alfa(i)) -cosd(theta(i))*(sind(alfa(i))) a(i)*sind(theta(i));
                           0               sind(alfa(i))                cosd(alfa(i))                 dis(i);
                           0                     0                          0                         1;];
                Tf=Tf*Tx;
                if i == 1
                    P1=Tf(1:3,4);
                    Z1c=Tf(1:3,3);
                end
                    if i == 2
                    P2=Tf(1:3,4);
                    Z2c=Tf(1:3,3);
                end
            end
            Tf;
            
            
            % Calculo Jacobiano Scara
            Z0=[0;0;1];
            Z1=[0;0;1];
            Z2=[0;0;-1];
            P0=[Px0;Py0; Pz0];
            %P3=Tf(1:3,4)
            %P3=[0;10;0]
            
            P3=[Pxf,-Pyf, Pzf];
            
            Jc=[Z0 Z1c Z2c; zeros(3)];
            Jcp=[Jc(1:3,1:3)];
            Jco=[Jc(4:6,1:3)];


            %Colubmas
             %Calcular Trayectoria
             Jc_1 = pinv(Jcp) ;%Jacobiano scara inverso DEBO EVALUAR EL JACOBIANO Posicion unicamente
             n=str2double(app.pasos_3d.Value);        %Numero de pasos
             mag_v=str2double(app.vel_3d.Value);      %Magnitud de v
             d=sqrt((P3(1)-P0(1))^2+(P3(2)-P0(2))^2+(P3(3)-P0(3))^2);
             u=[(P3(1)-P0(1)) (P3(2)-P0(2)) (P3(3)-P0(3))]/d;
             delt_t=d/(n*mag_v);
             Ve=mag_v*u;     %Velocidad
             qi=[0;0;0];
            
             q=zeros(n,3);
            
            
                for c=0:n
                    qi_1=(qi)+((Jc_1)*(Ve)'*(delt_t));
                    q(c+1,1)=qi(1); q(c+1,2)=qi(2); q(c+1,3)=qi(3);
            
                    qi=qi_1;
            
            
                end
             Robot.plot(q,'workspace',ws,'noname')
        end

        % Button pushed function: Limpiar
        function Limpiar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Limpiar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             cla (handles.axes1,'reset');
             set(handles.A,'String',' ');
             set(handles.B,'String',' ');
             set(handles.C,'String',' ');
             set(handles.D1,'String',' ');
             set(handles.D2,'String',' ');
             set(handles.D3,'String',' ');
        end

        % Value changed function: Slider1
        function Slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            %Obtenemos el valor de teta 3 deseado
            d1 = get(handles.Slider1,'value');
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            set(handles.A,'String',d1);
            
            t1 = 0;
            a1 = 0;
            alp1 = 90;
            
            %Matriz de transformacion
            A01= [cosd(t1)   -sind(t1)*cosd(alp1)    sind(t1)*sind(alp1)     a1*cosd(t1);
                  sind(t1)    cosd(t1)*cosd(alp1)   -cosd(t1)*sind(alp1)     a1*sind(t1);
                   0             sind(alp1)             cosd(alp1)                 d1;
                   0                0                      0                       1];
            
            %Imprimir datos de la tabla 1
            set(handles.Tabla1,'data',A01);
            
            % Matriz global de transformacion
            try
            MATRIZ_TRANSFORMACION=A01*A12*A23;
            catch
                 warning('Error');
            end
            %Visualizacion
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
        end

        % Value changed function: Slider2
        function Slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            d2 = get(handles.Slider2,'value');
            set(handles.B,'String',d2);
            
            t2 = 90;
            a2 = 0;
            alp2 = 90;
            
            %Matriz de transformacion
            A12=[ cosd(t2)    -sind(t2)*cosd(alp2)     sind(t2)*sind(alp2)    a2*cosd(t2);
                  sind(t2)    cosd(t2)*cosd(alp2)     -cosd(t2)*sind(alp2)    a2*sind(t2);
                   0              sind(alp2)              cosd(alp2)              d2;
                   0                 0                       0                     1];
            
            
            %Imprimir datos de la tabla 2
            set(handles.Tabla2,'data',A12);
            
            % Matriz global de transformacion
            try
            MATRIZ_TRANSFORMACION=A01*A12*A23;
            catch
                warning('Error');
            end
            %Visualizacion
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
        end

        % Value changed function: Slider3
        function Slider3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            d3 = get(handles.Slider3,'value');
            set(handles.C,'String',d3);
            
            t_3 = 0;
            a3 = 0;
            alp3 = 0;
            
            %Matriz de transformacion
            A23=[ cosd(t_3)   -sind(t_3)*cosd(alp3 )     sind(t_3)*sind(alp3 )    a3*cosd(t_3);
                  sind(t_3)    cosd(t_3)*cosd(alp3 )    -cosd(t_3)*sind(alp3 )    a3*sind(t_3);
                     0            sind(alp3 )               cosd(alp3 )                 d3;
                     0               0                      0                           1];
            
            %Imprimir datos de la tabla 3
            set(handles.Tabla3,'data',A23);
            
            % Matriz global de transformacion
            try
            MATRIZ_TRANSFORMACION=A01*A12*A23;
            catch
                warning('Error');
            end
            %Visualizacion
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
        end

        % Button pushed function: graficarLinks
        function graficarLinks_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');

            
            Pxf = str2double(app.xf_3d.Value);
            Pyf = str2double(app.yf_3d.Value);
            Pzf = str2double(app.zf_3d.Value);
            
            Px0 = str2double(app.x0_3d.Value);
            Py0 = str2double(app.y0_3d.Value);
            Pz0 = str2double(app.z0_3d.Value);
            
            %             teta       d       a      alfa
            L1 = Prismatic( 'a',0,'alpha',pi/2,'theta',0,'qlim',[0,25]);
            L2 = Prismatic( 'a',0,'alpha',pi/2,'theta',pi/2,'qlim',[0,25]);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Cartesiano';
            
            
            n=3;
            syms t1 t2 t4 d3 a1 a2
            d_1 = 150;
            d_2 = 183;
            d_3 = 148;
            
            theta=[0 90 0];
            alfa=[90 90 0];
            dis=[d_1 d_2 d_3];
            a=[0 0 0];
            Tf=1;
            
            for i=1:n
                Tx=[cosd(theta(i)) -sind(theta(i))*cosd(alfa(i)) sind(theta(i))*(sind(alfa(i)))  a(i)*cosd(theta(i));
                    sind(theta(i))  cosd(theta(i))*cosd(alfa(i)) -cosd(theta(i))*(sind(alfa(i))) a(i)*sind(theta(i));
                           0               sind(alfa(i))                cosd(alfa(i))                 dis(i);
                           0                     0                          0                         1;];
                Tf=Tf*Tx;
                if i == 1
                    P1=Tf(1:3,4);
                    Z1c=Tf(1:3,3);
                end
                    if i == 2
                    P2=Tf(1:3,4);
                    Z2c=Tf(1:3,3);
                end
            end
            Tf;
            
            
            % Calculo Jacobiano Scara
            Z0=[0;0;1];
            Z1=[0;0;1];
            Z2=[0;0;-1];
            P0=[Px0;Py0; Pz0];
            %P3=Tf(1:3,4)
            %P3=[0;10;0]
            
            P3=[Pxf,-Pyf, Pzf];
            
            Jc=[Z0 Z1c Z2c; zeros(3)];
            Jcp=[Jc(1:3,1:3)];
            Jco=[Jc(4:6,1:3)];


            %Colubmas
             %Calcular Trayectoria
             Jc_1 = pinv(Jcp) ;%Jacobiano scara inverso DEBO EVALUAR EL JACOBIANO Posicion unicamente
             n=str2double(app.pasos_3d.Value);        %Numero de pasos
             mag_v=str2double(app.vel_3d.Value);      %Magnitud de v
             d=sqrt((P3(1)-P0(1))^2+(P3(2)-P0(2))^2+(P3(3)-P0(3))^2);
             u=[(P3(1)-P0(1)) (P3(2)-P0(2)) (P3(3)-P0(3))]/d;
             delt_t=d/(n*mag_v);
             Ve=mag_v*u;     %Velocidad
             qi=[0;0;0];
            
             q=zeros(n,3);
            
            
                for c=0:n
                    qi_1=(qi)+((Jc_1)*(Ve)'*(delt_t));
                    q(c+1,1)=qi(1); q(c+1,2)=qi(2); q(c+1,3)=qi(3);
            
                    qi=qi_1;
            
            
                end
             plot(q(:,:));
             xlabel('Iteracion');
            ylabel('mm');
            legend({'Link 1','Link 2','Link 3'},'Location','southwest');
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            cla (handles.axes1,'reset');
            vi_1 = str2double(app.Vel_in1.Value);   %Vel inicial
            vi_2 = str2double(app.Vel_in2.Value);
            vi_3 = str2double(app.Vel_in3.Value);
            
            vf_1 = str2double(app.Vel_fin1.Value);   %Vel Final
            vf_2 = str2double(app.Vel_fin2.Value);
            vf_3 = str2double(app.Vel_fin3.Value);
            
              app.n = str2double(app.bt_n.Value);        %Numero de pasos
              tf = str2double(app.tf.Value);      %Magnitud de v
              app.tfgraf=str2double(app.tf.Value);
            
                 
                            %Perfil de Velocidad Cuadratica
                            
                %Por Cinematica Inversa
                di_1=0; %Pos iicial
                df_1=str2double(app.edit19.Value); %Theta Final
                
                cont=0;   %Contador
                
                di_2=0 %Pos iicial
                df_2=str2double(app.edit20.Value); %Theta Final
              
                di_3=0 %D iicial
                df_3=str2double(app.edit21.Value);  %D Final
              
                %Eslavon 1
                %Simbolivos de elementos a buscar
                syms a3_1 a2_1 a1_1 a0_1 
                
                %Ecuaciones
                a0_1 =di_1 
                a1_1 =vi_1
                q_1  =(a3_1*tf^3) + (a2_1*tf^2) + (a1_1*tf) + (a0_1)
                qp_1 =(3*a3_1*tf^2) + (2*a2_1*tf) + (a1_1)
                
                %Despejamos a2 y a3
                [a2_1,a3_1]=solve( [q_1;qp_1]==[df_1;vf_1], [a2_1,a3_1] )
                
                a2_1=double(a2_1)
                a3_1=double(a3_1)
                
                %Eslavon 2
                %Simbolivos de elementos a buscar
                syms a3_2 a2_2 a1_2 a0_2 
                
                %Ecuaciones
                a0_2 =di_2 
                a1_2 =vi_2
                q_2  =(a3_2*tf^3) + (a2_2*tf^2) + (a1_2*tf) + (a0_2)
                qp_2 =(3*a3_2*tf^2) + (2*a2_2*tf) + (a1_2)
                
                %Despejamos a2 y a3
                [a2_2,a3_2]=solve( [q_2;qp_2]==[df_2;vf_2], [a2_2,a3_2] )
                
                a2_2=double(a2_2)
                a3_2=double(a3_2)
                
                
                %Eslavon 3
                %Simbolivos de elementos a buscar
                syms a3_3 a2_3 a1_3 a0_3 
                
                %Ecuaciones
                a0_3 =di_3 
                a1_3 =vi_3
                q_3  =(a3_3*tf^3) + (a2_3*tf^2) + (a1_3*tf) + (a0_3)
                qp_3 =(3*a3_3*tf^2) + (2*a2_3*tf) + (a1_3)
                
                %Despejamos a2 y a3
                [a2_3,a3_3]=solve( [q_3;qp_3]==[df_3;vf_3], [a2_3,a3_3] )
                
                a2_3=double(a2_3)
                a3_3=double(a3_3)
                
                %Ya con los valores a de cada eslavon iteramos 
                %global vect vectv vecta tf n
                
               app.vect=zeros(app.n,3)
               app.vectv=zeros(app.n,3)
               app.vecta=zeros(app.n,3)
                
                for t=0:tf/app.n:tf
                    cont=cont+1;
                    
                    %Eslabon 1
                    d_1 =  a3_1*t^3 +  a2_1*t^2 + a1_1*t + a0_1
                    v_1     =3*a3_1*t^2 +2*a2_1*t   + a1_1
                    a_1     =6*a3_1*t   +  a2_1
                    app.vect(cont,1)=d_1
                    app.vectv(cont,1)=v_1
                    app.vecta(cont,1)=a_1
                    
                    %Eslabon 2
                    d_2 =  a3_2*t^3 +  a2_2*t^2 + a1_2*t + a0_2
                    v_2     =3*a3_2*t^2 +2*a2_2*t   + a1_2
                    a_2     =6*a3_2*t   +  a2_2
                    app.vect(cont,2)=d_2
                    app.vectv(cont,2)=v_2
                    app.vecta(cont,2)=a_2
                    
                    %Eslabon 3
                    d_3   =  a3_3*t^3 +  a2_3*t^2 + a1_3*t + a0_3
                    v_3     =3*a3_3*t^2 +2*a2_3*t   + a1_3
                    a_3     =6*a3_3*t   +  a2_3
                    app.vect(cont,3)=d_3
                    app.vectv(cont,3)=v_3
                    app.vecta(cont,3)=a_3
                end
        end

        % Button pushed function: PosicionButton
        function PosicionButtonPushed(app, event)
               x=0: app.tfgraf/app.n: app.tfgraf
                
                plot(x,app.vect)
                legend('D1','D2','D3');
                xlabel('n')
                ylabel('mm')
        end

        % Button pushed function: VelocidadButton
        function VelocidadButtonPushed(app, event)
             x=0: app.tfgraf/app.n: app.tfgraf
                
                plot(x,app.vectv)
                legend('D1','D2','D3');
                xlabel('n')
                ylabel('mm/n')
        end

        % Button pushed function: AceleracionButton
        function AceleracionButtonPushed(app, event)
             x=0: app.tfgraf/app.n: app.tfgraf
                plot(x,app.vecta)
                legend('D1','D2','D3');
                xlabel('n')
                ylabel('mm/n^2')
        end

        % Button pushed function: InterfazScaraButton
        function InterfazScaraButtonPushed(app, event)
            APP_SCARA
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'MANIPULADOR CARTESIANO';
            app.TabGroup = uitabgroup(app.figure1);
            app.CinamicaDirectaTab = uitab(app.TabGroup);
            app.CinamicaDirectaTab.Title = 'Cinamica Directa';
            app.SlidersPanel = uipanel(app.CinamicaDirectaTab);
            app.SlidersPanel.Title = 'Sliders';
            app.Slider1 = uislider(app.SlidersPanel);
            app.Slider1.Limits = [0 150];
            app.Slider1.MajorTicks = [];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1_Callback, true);
            app.Slider1.Tag = 'Slider1';
            app.Teta1 = uilabel(app.SlidersPanel);
            app.Teta1.Tag = 'Teta1';
            app.Teta1.Text = 'D1';
            app.text3 = uilabel(app.SlidersPanel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'D2';
            app.Slider2 = uislider(app.SlidersPanel);
            app.Slider2.Limits = [0 183];
            app.Slider2.MajorTicks = [];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2_Callback, true);
            app.Slider2.Tag = 'Slider2';
            app.Slider3 = uislider(app.SlidersPanel);
            app.Slider3.Limits = [0 148];
            app.Slider3.MajorTicks = [];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3_Callback, true);
            app.Slider3.Tag = 'Slider3';
            app.text4 = uilabel(app.SlidersPanel);
            app.text4.Tag = 'text4';
            app.text4.Text = 'D3';
            app.A = uieditfield(app.SlidersPanel, 'text');
            app.A.Tag = 'A';
            app.B = uieditfield(app.SlidersPanel, 'text');
            app.B.Tag = 'B';
            app.C = uieditfield(app.SlidersPanel, 'text');
            app.C.Tag = 'C';
            app.Boton3 = uibutton(app.SlidersPanel, 'push');
            app.Boton3.ButtonPushedFcn = createCallbackFcn(app, @Boton3_Callback, true);
            app.Boton3.Tag = 'Boton3';
            app.Boton3.Text = 'Cargar Slider´s';
            app.text8 = uilabel(app.SlidersPanel);
            app.text8.Tag = 'text8';
            app.text8.Text = '0';
            app.text9 = uilabel(app.SlidersPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = '150';
            app.text10 = uilabel(app.SlidersPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '0';
            app.text11 = uilabel(app.SlidersPanel);
            app.text11.Tag = 'text11';
            app.text11.Text = '183';
            app.text12 = uilabel(app.SlidersPanel);
            app.text12.Tag = 'text12';
            app.text12.Text = '0';
            app.text13 = uilabel(app.SlidersPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = '143';
            app.ManualPanel = uipanel(app.CinamicaDirectaTab);
            app.ManualPanel.Title = 'Manual';
            app.D1 = uieditfield(app.ManualPanel, 'text');
            app.D1.Tag = 'D1';
            app.D2 = uieditfield(app.ManualPanel, 'text');
            app.D2.Tag = 'D2';
            app.D3 = uieditfield(app.ManualPanel, 'text');
            app.D3.Tag = 'D3';
            app.text14 = uilabel(app.ManualPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Digite la distancia en D1';
            app.text15 = uilabel(app.ManualPanel);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Digite la distancia en D2';
            app.text16 = uilabel(app.ManualPanel);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Digite la distancia en D3';
            app.Boton1 = uibutton(app.ManualPanel, 'push');
            app.Boton1.ButtonPushedFcn = createCallbackFcn(app, @Boton1_Callback, true);
            app.Boton1.Tag = 'Boton1';
            app.Boton1.Text = 'Graficar';
            app.CinematicainversaTab = uitab(app.TabGroup);
            app.CinematicainversaTab.Title = 'Cinematica inversa';
            app.PosicionEfectorFinalPanel = uipanel(app.CinematicainversaTab);
            app.PosicionEfectorFinalPanel.Title = 'Posicion Efector Final';
            app.text22 = uilabel(app.PosicionEfectorFinalPanel);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Digite posicion en X del efector final';
            app.text23 = uilabel(app.PosicionEfectorFinalPanel);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Digite posicion en Y del efector final';
            app.text24 = uilabel(app.PosicionEfectorFinalPanel);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Digite posicion en Z del efector';
            app.EfectorX = uieditfield(app.PosicionEfectorFinalPanel, 'text');
            app.EfectorX.Tag = 'EfectorX';
            app.EfectorY = uieditfield(app.PosicionEfectorFinalPanel, 'text');
            app.EfectorY.Tag = 'EfectorY';
            app.EfectorZ = uieditfield(app.PosicionEfectorFinalPanel, 'text');
            app.EfectorZ.Tag = 'EfectorZ';
            app.DistanciasObtenidasPanel = uipanel(app.CinematicainversaTab);
            app.DistanciasObtenidasPanel.Title = 'Distancias Obtenidas';
            app.edit19 = uieditfield(app.DistanciasObtenidasPanel, 'text');
            app.edit19.Tag = 'edit19';
            app.edit20 = uieditfield(app.DistanciasObtenidasPanel, 'text');
            app.edit20.Tag = 'edit20';
            app.text28 = uilabel(app.DistanciasObtenidasPanel);
            app.text28.Tag = 'text28';
            app.text28.Text = 'D2';
            app.text29 = uilabel(app.DistanciasObtenidasPanel);
            app.text29.Tag = 'text29';
            app.text29.Text = 'D3';
            app.edit21 = uieditfield(app.DistanciasObtenidasPanel, 'text');
            app.edit21.Tag = 'edit21';
            app.text30 = uilabel(app.DistanciasObtenidasPanel);
            app.text30.Tag = 'text30';
            app.text30.Text = 'D1';
            app.BotonEF = uibutton(app.CinematicainversaTab, 'push');
            app.BotonEF.ButtonPushedFcn = createCallbackFcn(app, @BotonEF_Callback, true);
            app.BotonEF.Tag = 'BotonEF';
            app.BotonEF.Text = 'Calcular';
            app.Grafica3DTab = uitab(app.TabGroup);
            app.Grafica3DTab.Title = 'Grafica 3D';
            app.PosicionPanel = uipanel(app.Grafica3DTab);
            app.PosicionPanel.Title = 'Posicion';
            app.text31 = uilabel(app.PosicionPanel);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Posicion Inicial Mov 3D';
            app.x0_3d = uieditfield(app.PosicionPanel, 'text');
            app.x0_3d.Tag = 'edit26';
            app.x0_3d.Value = '0';
            app.z0_3d = uieditfield(app.PosicionPanel, 'text');
            app.z0_3d.Tag = 'edit27';
            app.z0_3d.Value = '0';
            app.y0_3d = uieditfield(app.PosicionPanel, 'text');
            app.y0_3d.Tag = 'edit28';
            app.y0_3d.Value = '0';
            app.XoLabel = uilabel(app.PosicionPanel);
            app.XoLabel.Text = 'Xo';
            app.YoLabel = uilabel(app.PosicionPanel);
            app.YoLabel.Text = 'Yo';
            app.ZoLabel = uilabel(app.PosicionPanel);
            app.ZoLabel.Text = 'Zo';
            app.text31_2 = uilabel(app.PosicionPanel);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'Posicion Inicial Mov 3D';
            app.xf_3d = uieditfield(app.PosicionPanel, 'text');
            app.xf_3d.Tag = 'edit26';
            app.xf_3d.Value = '50';
            app.zf_3d = uieditfield(app.PosicionPanel, 'text');
            app.zf_3d.Tag = 'edit27';
            app.zf_3d.Value = '50';
            app.yf_3d = uieditfield(app.PosicionPanel, 'text');
            app.yf_3d.Tag = 'edit28';
            app.yf_3d.Value = '50';
            app.XfLabel = uilabel(app.PosicionPanel);
            app.XfLabel.Text = 'Xf';
            app.YfLabel = uilabel(app.PosicionPanel);
            app.YfLabel.Text = 'Yf';
            app.ZfLabel = uilabel(app.PosicionPanel);
            app.ZfLabel.Text = 'Zf';
            app.ConfiguracionesPanel = uipanel(app.Grafica3DTab);
            app.ConfiguracionesPanel.Title = 'Configuraciones';
            app.pasos_3d = uieditfield(app.ConfiguracionesPanel, 'text');
            app.pasos_3d.Tag = 'edit24';
            app.pasos_3d.Value = '150';
            app.vel_3d = uieditfield(app.ConfiguracionesPanel, 'text');
            app.vel_3d.Tag = 'edit25';
            app.vel_3d.Value = '0.1';
            app.PasosLabel = uilabel(app.ConfiguracionesPanel);
            app.PasosLabel.Text = '# Pasos';
            app.VelMovimientoLabel = uilabel(app.ConfiguracionesPanel);
            app.VelMovimientoLabel.Text = 'Vel Movimiento';
            app.Graficar_3d = uibutton(app.Grafica3DTab, 'push');
            app.Graficar_3d.ButtonPushedFcn = createCallbackFcn(app, @Graficar_3d_Callback, true);
            app.Graficar_3d.Tag = 'Graficar_3d';
            app.Graficar_3d.Text = 'Graficar 3D';
            app.graficarLinks = uibutton(app.Grafica3DTab, 'push');
            app.graficarLinks.ButtonPushedFcn = createCallbackFcn(app, @graficarLinks_Callback, true);
            app.graficarLinks.Tag = 'pushbutton9';
            app.graficarLinks.Text = 'Graficar Links';
            app.TablaDH = uitab(app.TabGroup);
            app.TablaDH.Title = 'Tabla DH';
            app.TabGroup2 = uitabgroup(app.TablaDH);
            app.A01Tab = uitab(app.TabGroup2);
            app.A01Tab.Title = 'A01';
            app.text17_2 = uilabel(app.A01Tab);
            app.text17_2.Tag = 'text17';
            app.text17_2.Text = 'A01';
            app.Tabla1 = uitable(app.A01Tab);
            app.Tabla1.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.Tabla1.RowName = {'1'; '2'; '3'; ''};
            app.Tabla1.ColumnEditable = [false false false false];
            app.Tabla1.Tag = 'Tabla1';
            app.A12Tab = uitab(app.TabGroup2);
            app.A12Tab.Title = 'A12';
            app.text18_2 = uilabel(app.A12Tab);
            app.text18_2.Tag = 'text18';
            app.text18_2.Text = 'A12';
            app.Tabla2 = uitable(app.A12Tab);
            app.Tabla2.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.Tabla2.RowName = {'1'; '2'; '3'; ''};
            app.Tabla2.ColumnEditable = [false false false false];
            app.Tabla2.Tag = 'Tabla2';
            app.A23Tab = uitab(app.TabGroup2);
            app.A23Tab.Title = 'A23';
            app.text19_2 = uilabel(app.A23Tab);
            app.text19_2.Tag = 'text19';
            app.text19_2.Text = 'A23';
            app.Tabla3 = uitable(app.A23Tab);
            app.Tabla3.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.Tabla3.RowName = {'1'; '2'; '3'; ''};
            app.Tabla3.ColumnEditable = [false false false false];
            app.Tabla3.Tag = 'Tabla3';
            app.ATTab = uitab(app.TabGroup2);
            app.ATTab.Title = 'AT';
            app.text20_2 = uilabel(app.ATTab);
            app.text20_2.Tag = 'text20';
            app.text20_2.Text = 'A T';
            app.TablaT = uitable(app.ATTab);
            app.TablaT.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.TablaT.RowName = {'1'; '2'; '3'; ''};
            app.TablaT.ColumnEditable = [false false false false];
            app.TablaT.Tag = 'TablaT';
            app.PerfilCuadraticoTab = uitab(app.TabGroup);
            app.PerfilCuadraticoTab.Title = 'Perfil Cuadratico';
            app.CalcularButton = uibutton(app.PerfilCuadraticoTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.VelocidadPanel = uipanel(app.PerfilCuadraticoTab);
            app.VelocidadPanel.Title = 'Velocidad';
            app.Vel_in1 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_in1.Tag = 'bt_Xo';
            app.Vel_in1.Value = '10';
            app.Vel_in3 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_in3.Tag = 'bt_Zo';
            app.Vel_in3.Value = '10';
            app.Vel_in2 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_in2.Tag = 'bt_Yo';
            app.Vel_in2.Value = '10';
            app.text31_3 = uilabel(app.VelocidadPanel);
            app.text31_3.Tag = 'text31';
            app.text31_3.Text = 'Inicial';
            app.text31_4 = uilabel(app.VelocidadPanel);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'Final';
            app.Vel_fin1 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_fin1.Tag = 'edit26';
            app.Vel_fin1.Value = '30';
            app.Vel_fin3 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_fin3.Tag = 'edit27';
            app.Vel_fin3.Value = '30';
            app.Vel_fin2 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_fin2.Tag = 'edit28';
            app.Vel_fin2.Value = '20';
            app.VeFi1Label = uilabel(app.VelocidadPanel);
            app.VeFi1Label.Text = 'VeFi1';
            app.VeFi2Label = uilabel(app.VelocidadPanel);
            app.VeFi2Label.Text = 'VeFi2';
            app.VeFi3Label = uilabel(app.VelocidadPanel);
            app.VeFi3Label.Text = 'VeFi3';
            app.VeIn1Label = uilabel(app.VelocidadPanel);
            app.VeIn1Label.Text = 'VeIn1';
            app.VeIn2Label = uilabel(app.VelocidadPanel);
            app.VeIn2Label.Text = 'VeIn2';
            app.VeIn3Label = uilabel(app.VelocidadPanel);
            app.VeIn3Label.Text = 'VeIn3';
            app.ConfiguracionesPanel_2 = uipanel(app.PerfilCuadraticoTab);
            app.ConfiguracionesPanel_2.Title = 'Configuraciones';
            app.bt_n = uieditfield(app.ConfiguracionesPanel_2, 'text');
            app.bt_n.Tag = 'bt_n';
            app.bt_n.Value = '150';
            app.tf = uieditfield(app.ConfiguracionesPanel_2, 'text');
            app.tf.Tag = 'bt_v';
            app.tf.Value = '20';
            app.PasosLabel_2 = uilabel(app.ConfiguracionesPanel_2);
            app.PasosLabel_2.Text = '# Pasos';
            app.TiempoFinalLabel = uilabel(app.ConfiguracionesPanel_2);
            app.TiempoFinalLabel.Text = 'Tiempo Final';
            app.PorfavorprimerorealiceCinematicaInversaLabel = uilabel(app.PerfilCuadraticoTab);
            app.PorfavorprimerorealiceCinematicaInversaLabel.Text = 'Por favor primero realice Cinematica Inversa';
            app.VelocidadButton = uibutton(app.PerfilCuadraticoTab, 'push');
            app.VelocidadButton.ButtonPushedFcn = createCallbackFcn(app, @VelocidadButtonPushed, true);
            app.VelocidadButton.Text = 'Velocidad';
            app.AceleracionButton = uibutton(app.PerfilCuadraticoTab, 'push');
            app.AceleracionButton.ButtonPushedFcn = createCallbackFcn(app, @AceleracionButtonPushed, true);
            app.AceleracionButton.Text = 'Aceleracion';
            app.PosicionButton = uibutton(app.PerfilCuadraticoTab, 'push');
            app.PosicionButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionButtonPushed, true);
            app.PosicionButton.Text = 'Posicion';
            app.PosicionActualPanel = uipanel(app.figure1);
            app.PosicionActualPanel.Title = 'Posicion Actual';
            app.text5_2 = uilabel(app.PosicionActualPanel);
            app.text5_2.Tag = 'text5';
            app.text5_2.Text = 'Posicion Eje X';
            app.text6_2 = uilabel(app.PosicionActualPanel);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'Posicion Eje Y';
            app.text7_2 = uilabel(app.PosicionActualPanel);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'Posicion Eje Z';
            app.Limpiar = uibutton(app.PosicionActualPanel, 'push');
            app.Limpiar.ButtonPushedFcn = createCallbackFcn(app, @Limpiar_Callback, true);
            app.Limpiar.Tag = 'Limpiar';
            app.Limpiar.Text = 'Limpiar';
            app.PosX = uieditfield(app.PosicionActualPanel, 'text');
            app.PosX.Tag = 'PosX';
            app.PosY = uieditfield(app.PosicionActualPanel, 'text');
            app.PosY.Tag = 'PosY';
            app.PosZ = uieditfield(app.PosicionActualPanel, 'text');
            app.PosZ.Tag = 'PosZ';
            app.InterfazScaraButton = uibutton(app.figure1, 'push');
            app.InterfazScaraButton.ButtonPushedFcn = createCallbackFcn(app, @InterfazScaraButtonPushed, true);
            app.InterfazScaraButton.Text = 'Interfaz Scara';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Cartesiano(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end