classdef APP_SCARA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        MovimientoButton           matlab.ui.control.Button
        InterfazCartesianoButton   matlab.ui.control.Button
        PosicionActualPanel        matlab.ui.container.Panel
        Limpiar                    matlab.ui.control.Button
        PosZ                       matlab.ui.control.EditField
        text7                      matlab.ui.control.Label
        text6                      matlab.ui.control.Label
        PosY                       matlab.ui.control.EditField
        text5                      matlab.ui.control.Label
        PosX                       matlab.ui.control.EditField
        TabGroup                   matlab.ui.container.TabGroup
        CinamicaDirectaTab         matlab.ui.container.Tab
        ManualPanel                matlab.ui.container.Panel
        text16                     matlab.ui.control.Label
        text15                     matlab.ui.control.Label
        text14                     matlab.ui.control.Label
        Theta3                     matlab.ui.control.EditField
        Theta2                     matlab.ui.control.EditField
        Theta1                     matlab.ui.control.EditField
        Boton1                     matlab.ui.control.Button
        SlidersPanel               matlab.ui.container.Panel
        text13                     matlab.ui.control.Label
        text12                     matlab.ui.control.Label
        text11                     matlab.ui.control.Label
        text10                     matlab.ui.control.Label
        text9                      matlab.ui.control.Label
        text8                      matlab.ui.control.Label
        Boton3                     matlab.ui.control.Button
        C                          matlab.ui.control.EditField
        B                          matlab.ui.control.EditField
        A                          matlab.ui.control.EditField
        text4                      matlab.ui.control.Label
        Slider3                    matlab.ui.control.Slider
        Slider2                    matlab.ui.control.Slider
        text3                      matlab.ui.control.Label
        Teta1                      matlab.ui.control.Label
        Slider1                    matlab.ui.control.Slider
        CinematicainversaTab       matlab.ui.container.Tab
        BotonEU                    matlab.ui.control.Button
        BotonEF                    matlab.ui.control.Button
        AngulosObtenidosPanel      matlab.ui.container.Panel
        text30                     matlab.ui.control.Label
        text28                     matlab.ui.control.Label
        Theta2EU                   matlab.ui.control.EditField
        Theta1EU                   matlab.ui.control.EditField
        text27                     matlab.ui.control.Label
        D3E                        matlab.ui.control.EditField
        text26                     matlab.ui.control.Label
        text25                     matlab.ui.control.Label
        Theta2ED                   matlab.ui.control.EditField
        Theta1ED                   matlab.ui.control.EditField
        PosicionEfectorFinalPanel  matlab.ui.container.Panel
        text24                     matlab.ui.control.Label
        text23                     matlab.ui.control.Label
        text22                     matlab.ui.control.Label
        EfectorZ                   matlab.ui.control.EditField
        EfectorY                   matlab.ui.control.EditField
        EfectorX                   matlab.ui.control.EditField
        Grafica3DTab               matlab.ui.container.Tab
        ConfiguracionesPanel       matlab.ui.container.Panel
        VelMovimientoLabel         matlab.ui.control.Label
        PasosLabel                 matlab.ui.control.Label
        bt_v                       matlab.ui.control.EditField
        bt_n                       matlab.ui.control.EditField
        Graficar                   matlab.ui.control.Button
        Graf_Links                 matlab.ui.control.Button
        PosicionPanel              matlab.ui.container.Panel
        ZoLabel                    matlab.ui.control.Label
        YoLabel                    matlab.ui.control.Label
        XoLabel                    matlab.ui.control.Label
        ZfLabel                    matlab.ui.control.Label
        YfLabel                    matlab.ui.control.Label
        XfLabel                    matlab.ui.control.Label
        yf_3d                      matlab.ui.control.EditField
        zf_3d                      matlab.ui.control.EditField
        xf_3d                      matlab.ui.control.EditField
        text31_2                   matlab.ui.control.Label
        text31                     matlab.ui.control.Label
        bt_Yo                      matlab.ui.control.EditField
        bt_Zo                      matlab.ui.control.EditField
        bt_Xo                      matlab.ui.control.EditField
        TablaDH                    matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        A1Tab                      matlab.ui.container.Tab
        text17                     matlab.ui.control.Label
        Tabla1                     matlab.ui.control.Table
        A2Tab                      matlab.ui.container.Tab
        text18                     matlab.ui.control.Label
        Tabla2                     matlab.ui.control.Table
        A2Tab_2                    matlab.ui.container.Tab
        text19                     matlab.ui.control.Label
        Tabla3                     matlab.ui.control.Table
        A3Tab                      matlab.ui.container.Tab
        text20                     matlab.ui.control.Label
        TablaT                     matlab.ui.control.Table
        PerfilCuadraticoTab        matlab.ui.container.Tab
        PosicionButton_2           matlab.ui.control.Button
        AceleracionButton          matlab.ui.control.Button
        segButton                  matlab.ui.control.Button
        PorfavorprimerorealiceCinematicaInversaLabel  matlab.ui.control.Label
        ConfiguracionesPanel_2     matlab.ui.container.Panel
        TiempoFinalLabel           matlab.ui.control.Label
        PasosLabel_2               matlab.ui.control.Label
        tf                         matlab.ui.control.EditField
        bt_n_2                     matlab.ui.control.EditField
        VelocidadPanel             matlab.ui.container.Panel
        VeIn3Label                 matlab.ui.control.Label
        VeIn2Label                 matlab.ui.control.Label
        VeIn1Label                 matlab.ui.control.Label
        VeFi3Label                 matlab.ui.control.Label
        VeFi2Label                 matlab.ui.control.Label
        VeFi1Label                 matlab.ui.control.Label
        Vel_fin2                   matlab.ui.control.EditField
        Vel_fin3                   matlab.ui.control.EditField
        Vel_fin1                   matlab.ui.control.EditField
        text31_4                   matlab.ui.control.Label
        text31_3                   matlab.ui.control.Label
        Vel_in2                    matlab.ui.control.EditField
        Vel_in3                    matlab.ui.control.EditField
        Vel_in1                    matlab.ui.control.EditField
        CalcularButton             matlab.ui.control.Button
        Aviso                      matlab.ui.control.EditField
        text21                     matlab.ui.control.Label
        axes1                      matlab.ui.control.UIAxes
    end

    %Aqui se declara variables globales
    properties (Access = private)
        vect  %Vector de posicion
        vecta %Vector de aceleracion
        vectv %Vector de velocidad
        n     %Resolucion
        tfgraf % Description
    end
    
    methods (Access = private)
        function Boton2_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Boton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
            PX = str2double(handles.PosX.String);
            PY = str2double(handles.PosY.String);
            PZ = str2double(handles.PosZ.String);
            
            L_1 = 149; %a1
            L_2 = 142; %a2
            L_3 = 125; %d1
            L_4 = 0;
            L_5 = 0;
            L_6 = 0;
            %            teta     d      a        alfa
            L(1) = Link([ 0      L_1     0      pi/2]);
            L(2) = Link([ 0       0     L_2       0]);
            L(3) = Link([ 0       0      L_3      0]);
            L(4) = Link([0 0 L_4 -pi/2]);
            L(5) = Link([0 0 L_5 pi/2]);
            L(6) = Link([0 L_6 0 0]);
            
            
            Robot = SerialLink(L);
            Robot.name = 'Six_R_Robot';
            
            T = [ 1 0 0 PX;
                  0 1 0 PY;
                  0 0 1 PZ;
                  0 0 0 1];
            
            J = Robot.ikine(T,[0 0 0],'mask',[1 1 1 0 0 0])*180/pi;
            handles.Theta1.String = num2str(floor(J(1)));
            handles.Theta2.String = num2str(floor(J(2)));
            handles.Theta3.String = num2str(floor(J(3)));
            handles.Theta4.String = num2str(floor(J(3)));
            handles.Theta5.String = num2str(floor(J(3)));
            handles.Theta5.String = num2str(floor(J(3)));
            
            Robot.plot(J*pi/180);
        end
        
        function Theta4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Theta4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Theta5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Theta5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Theta6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Theta6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function x_3d_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to x_3d (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function y_3d_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to y_3d (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function z_3d_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to z_3d (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.Tabla2.ColumnFormat = {[] [] [] []};
            app.Tabla3.ColumnFormat = {[] [] [] []};
            app.TablaT.ColumnFormat = {[] [] [] []};
            app.Tabla1.BackgroundColor = [1 1 1];
            app.Tabla1.ColumnFormat = {[] [] [] []};
        end
        
    function  vect = movimiento(thi_1, thf_1,thi_2,thf_2,di_3,df_3)
            %Perfil de Velocidad Cuadratica
                
                %Datos Entranda
                n=100 ;     %Iteraciones
                tf=5 ;    %Tiempo Final
                
                %Por Cinematica Inversa
%                 thi_1=100 %Theta iicial
%                 thf_1=150 %Theta Final
                vi_1=0 ;   %Vel inicial
                vf_1=0 ;  %Vel Final
                
                cont=0;   %Contador
                 
%                 thi_2=100 %Theta iicial
%                 thf_2=150 %Theta Final
                vi_2=0 ;  %Vel inicial
                vf_2=0 ;  %Vel Final
                
%                 di_3=100  %D iicial
%                 df_3=150  %D Final
                vi_3=0  ;  %Vel inicial
                vf_3=0 ;  %Vel Final
                
                
                %Eslavon 1
                %Simbolivos de elementos a buscar
                syms a3_1 a2_1 a1_1 a0_1 
                
                %Ecuaciones
                a0_1 =thi_1 ;
                a1_1 =vi_1;
                q_1  =(a3_1*tf^3) + (a2_1*tf^2) + (a1_1*tf) + (a0_1);
                qp_1 =(3*a3_1*tf^2) + (2*a2_1*tf) + (a1_1);
                
                %Despejamos a2 y a3
                [a2_1,a3_1]=solve( [q_1;qp_1]==[thf_1;vf_1], [a2_1,a3_1] )
                ;
                a2_1=double(a2_1);
                a3_1=double(a3_1);
                
                %Eslavon 2
                %Simbolivos de elementos a buscar
                syms a3_2 a2_2 a1_2 a0_2 
                
                %Ecuaciones
                a0_2 =thi_2 ;
                a1_2 =vi_2;
                q_2  =(a3_2*tf^3) + (a2_2*tf^2) + (a1_2*tf) + (a0_2);
                qp_2 =(3*a3_2*tf^2) + (2*a2_2*tf) + (a1_2);
                
                %Despejamos a2 y a3
                [a2_2,a3_2]=solve([q_2;qp_2]==[thf_2;vf_2], [a2_2,a3_2]);
                
                a2_2=double(a2_2);
                a3_2=double(a3_2);
                
                
                %Eslavon 3
                %Simbolivos de elementos a buscar
                syms a3_3 a2_3 a1_3 a0_3 
                
                %Ecuaciones
                a0_3 =di_3 ;
                a1_3 =vi_3;
                q_3  =(a3_3*tf^3) + (a2_3*tf^2) + (a1_3*tf) + (a0_3);
                qp_3 =(3*a3_3*tf^2) + (2*a2_3*tf) + (a1_3);
                
                %Despejamos a2 y a3
                [a2_3,a3_3]=solve( [q_3;qp_3]==[df_3;vf_3], [a2_3,a3_3] );
                
                a2_3=double(a2_3);
                a3_3=double(a3_3);
                
                %Ya con los valores a de cada eslavon iteramos 
                
                vect=zeros(n,3);
                
                for t=0:tf/n:tf;
                    cont=cont+1;
                    
                    %Eslabon 1
                    theta_1 =  a3_1*t^3 +  a2_1*t^2 + a1_1*t + a0_1;
                    v_1     =3*a3_1*t^2 +2*a2_1*t   + a1_1;
                    a_1     =6*a3_1*t   +  a2_1;
                    vect(cont,1)=theta_1;
                    
                    %Eslabon 2
                    theta_2 =  a3_2*t^3 +  a2_2*t^2 + a1_2*t + a0_2;
                    v_2     =3*a3_2*t^2 +2*a2_2*t   + a1_2;
                    a_2     =6*a3_2*t   +  a2_2;
                    vect(cont,2)=theta_2;
                    
                    %Eslabon 3
                    Dis_3   =  a3_3*t^3 +  a2_3*t^2 + a1_3*t + a0_3;
                    v_3     =3*a3_3*t^2 +2*a2_3*t   + a1_3;
                    a_3     =6*a3_3*t   +  a2_3;
                    vect(cont,3)=Dis_3;
                    
                end
    end
    function gripper(clientID,closing,j1,j2)
            sim=remApi('remoteApi');
            [returnCode,p1]=sim.simxGetJointPosition(clientID,j1,sim.simx_opmode_blocking);
            [returnCode,p2]=sim.simxGetJointPosition(clientID,j1,sim.simx_opmode_blocking);
            if(closing==1)
                if(p1<(p2-0.008))
                    sim.simxSetJointTargetVelocity(clientID,j1,-0.01,sim.simx_opmode_blocking);
                    sim.simxSetJointTargetVelocity(clientID,j2,-0.04,sim.simx_opmode_blocking);
                else
                    sim.simxSetJointTargetVelocity(clientID,j1,-0.04,sim.simx_opmode_blocking);
                    sim.simxSetJointTargetVelocity(clientID,j2,-0.04,sim.simx_opmode_blocking);
                end
            else
                if(p1<p2)
                    sim.simxSetJointTargetVelocity(clientID,j1,0.04,sim.simx_opmode_blocking);
                    sim.simxSetJointTargetVelocity(clientID,j2,0.02,sim.simx_opmode_blocking);
                else
                    sim.simxSetJointTargetVelocity(clientID,j1,0.02,sim.simx_opmode_blocking);
                    sim.simxSetJointTargetVelocity(clientID,j2,0.04,sim.simx_opmode_blocking);
                end
            end
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI (see VARARGIN)
            
            handles.output = hObject;
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            % Update handles structure
            guidata(hObject, handles);
            
            A01 = [ 1  0  0  149;
                    0  1  0   0;
                    0  0  1  167;
                    0  0  0   1];
            
            A12 = [1     0              0       112;
                   0    -1         -1.225e-16    0 ;
                   0  1.225e-16        -1        0 ;
                   0    0              0         1 ];
            
            A23 = [1 0 0 0;
                   0 1 0 0 ;
                   0 0 1 0;
                   0 0 0 1];
            set(handles.Tabla1,'data',A01);
            set(handles.Tabla2,'data',A12);
            set(handles.Tabla3,'data',A23);
            MATRIZ_TRANSFORMACION = A01*A12*A23;
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
            
%             A_Callback(handles.A,[],handles);
%             B_Callback(handles.B,[],handles);
%             C_Callback(handles.C,[],handles);
            
            
            % Choose default command line output for GUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Boton1
        function Boton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Boton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
            Th_1 = str2double(handles.Theta1.String)*pi/180;
            Th_2 = str2double(handles.Theta2.String)*pi/180;
            d_3 = str2double(handles.Theta3.String);
            
            L_1 = 149; %a1
            L_2 = 142; %a2
            L_3 = 125; %d1
            
            %             teta       d       a      alfa
            L1 = Revolute( 'a',L_1,'alpha',0,'d',L_3);
            L2 = Revolute( 'a',L_2,'alpha',pi,'d',0);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Scara';
            ws=[-450,450,-450,450,0,200]
            
            Robot.plot([Th_1, Th_2, d_3],'workspace',ws,'noname');
            
            T = Robot.fkine([Th_1 Th_2 d_3]);
            handles.PosX.String = num2str(floor(T.t(1)));
            handles.PosY.String = num2str(floor(T.t(2)));
            handles.PosZ.String = num2str(floor(T.t(3)));
        end

        % Button pushed function: Boton3
        function Boton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Boton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
            t1 = get(handles.Slider1,'value');
            t2 = get(handles.Slider2,'value');
            d3 = get(handles.Slider3,'value');
            
            t1b3 = t1*pi/180;
            t2b3 = t2*pi/180;
            t3b3 = d3;
            
            % t3b3 =str2double(get(handles.t3,'String'));
            
            L_1 = 149; %a1
            L_2 = 142; %a2
            L_3 = 125; %d1
            
            
            %             teta       d       a      alfa
            L1 = Revolute( 'a',L_1,'alpha',0,'d',L_3);
            L2 = Revolute( 'a',L_2,'alpha',pi,'d',0);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Scara';
            ws=[-450,450,-450,450,0,200]
            
            
            Robot.plot([t1b3, t2b3, t3b3],'workspace',ws,'noname');
            
            T = Robot.fkine([t1b3 t2b3 t3b3]);
            handles.PosX.String = num2str(floor(T.t(1)));
            handles.PosY.String = num2str(floor(T.t(2)));
            handles.PosZ.String = num2str(floor(T.t(3)));
        end

        % Button pushed function: BotonEF
        function BotonEF_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BotonEF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
            Px = str2double(handles.EfectorX.String);
            Py = str2double(handles.EfectorY.String);
            Pz = str2double(handles.EfectorZ.String);
            
            L_1 = 149; %a1
            L_2 = 142; %a2
            L_3 = 125; %d1
            
            %             teta       d       a      alfa
            L1 = Revolute( 'a',L_1,'alpha',0,'d',L_3);
            L2 = Revolute( 'a',L_2,'alpha',pi,'d',0);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Scara';
            ws=[-450,450,-450,450,0,200];
            
            %Codo Abajo
            
            %Cordenadas Punto Final
            px_ca=Px;
            py_ca=Py;
            a1_ca=149;
            a2_ca=112;
            
            %Calculamos H
            H_ca=sqrt((px_ca^2)+(py_ca^2));
            
            %Calculamos th2
            th2_ca=acos(((H_ca^2)-(a1_ca^2)-(a2_ca^2))/(2*a1_ca*a2_ca));
            th2_ca_grad=rad2deg(th2_ca);
            
            %calculamos th1
            th1_ca=atan2(py_ca,px_ca)-atan2(a2_ca*sin(th2_ca),a1_ca+a2_ca*cos(th2_ca));
            th1_ca_grad=rad2deg(th1_ca);


            %Graficar
            Robot.plot([th1_ca th2_ca Pz],'workspace',ws,'noname');
            %Mensaje Error
            th1_e=th1_ca;
            th2_e=th2_ca;
            d3=Pz;
            
            th1_ef=0;
            th2_ef=0;
            
            th1_e_r = mod(th1_e,2*pi);
                if th1_e_r>pi
                 th1_ef =th1_e_r-(2*pi);
                end
            
            th2_e_r = mod(th2_e,2*pi);
                if th2_e_r>pi
                 th2_ef =th2_e_r-(2*pi);
                end
            
            if th1_ef>(2*pi) || th1_ef<(-2*pi) ||th2_ef>(0.89*pi) || th2_ef<(-0.89*pi) || d3>100 || d3<0 % (0.89*pi)=160 grad
                 warndlg('COORDENADAS NO VALIDAS','Mensaje de error');
            end
            %Matriz confirmar datos
            T = Robot.fkine([th1_ca th2_ca Pz]);
            %Rellenar cuadros Texto
            handles.Theta1ED.String = num2str(floor(th1_ca_grad));
            handles.Theta2ED.String = num2str(floor(th2_ca_grad));
            handles.D3E.String = num2str(floor(Pz));
            
            handles.PosX.String = num2str(floor(T.t(1)));
            handles.PosY.String = num2str(floor(T.t(2)));
            handles.PosZ.String = num2str(floor(T.t(3)));
        end

        % Button pushed function: BotonEU
        function BotonEU_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BotonEU (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
            Px = str2double(handles.EfectorX.String);
            Py = str2double(handles.EfectorY.String);
            Pz = str2double(handles.EfectorZ.String);
            
            L_1 = 149; %a1
            L_2 = 142; %a2
            L_3 = 125; %d1
            %             teta       d       a      alfa
            L1 = Revolute( 'a',L_1,'alpha',0,'d',L_3);
            L2 = Revolute( 'a',L_2,'alpha',pi,'d',0);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            Robot = SerialLink([L1,L2,L3]);
            Robot.name = 'Robot Scara';
            ws=[-450,450,-450,450,0,200]
            
            %Codo Arriba
            
            %Cordenadas Punto Final
            px=Px;
            py=Py;
            a1=149;
            a2=112;
            
            %Calculamos H
            H=sqrt((px^2)+(py^2));
            %Calculamos alpha
            aph1=atan2(py,px);
            
            %Calculamos beta 1
            bt1=acos(((H^2)+(a1^2)-(a2^2))/(2*H*a1));
            
            %Calculamos R
            r=acos(((H^2)-(a2^2)-(a1^2))/(2*a2*a1));% es un angulo
            
            %Calculamos th1 y th2
            th1=aph1+bt1;
            th1_grad=rad2deg(th1);
            th2=pi-r;
            th2_grad=rad2deg(th2);
            
            %Graficar
            Robot.plot([th1 th2 Pz],'workspace',ws,'noname');
            %Mensaje Error
            th1_e=th1;
            th2_e=th2;
            d3=Pz;
            
            th1_ef=0;
            th2_ef=0;
            
            th1_e_r = mod(th1_e,2*pi);
                if th1_e_r>pi
                 th1_ef =th1_e_r-(2*pi);
                end
            
            th2_e_r = mod(th2_e,2*pi);
                if th2_e_r>pi
                 th2_ef =th2_e_r-(2*pi);
                end
            
            if th1_ef>(2*pi) || th1_ef<(-2*pi) ||th2_ef>(0.89*pi) || th2_ef<(-0.89*pi) || d3>100 || d3<0 % (0.89*pi)=160 grad
                 warndlg('COORDENADAS NO VALIDAS','Mensaje de error');
            end
            %Matriz confirmar datos
            T = Robot.fkine([th1 th2 Pz]);
            %Rellenar cuadros Texto
            handles.Theta1ED.String = num2str(floor(th1_grad));
            handles.Theta2ED.String = num2str(floor(th2_grad));
            handles.PosZ.String = num2str(floor(Pz));
            
            handles.PosX.String = num2str(floor(T.t(1)));
            handles.PosY.String = num2str(floor(T.t(2)));
            handles.PosZ.String = num2str(floor(T.t(3)));
        end

        % Button pushed function: Graf_Links
        function Graf_Links_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Graf_Links (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla (handles.axes1,'reset');
            Px0 = str2double(app.bt_Xo.Value);
            Py0 = str2double(app.bt_Yo.Value);
            Pz0 = str2double(app.bt_Zo.Value);
            
            Pxf = str2double(app.xf_3d.Value);
            Pyf = str2double(app.yf_3d.Value);
            Pzf = str2double(app.zf_3d.Value);
            
            n=150%str2double(app.bt_n.Value);        %Numero de pasos
            mag_v=str2double(app.bt_v.Value);      %Magnitud de v
            
            L_1 = 149; %a1
            L_2 = 142; %a2
            L_3 = 125; %d1
            
            %             teta       d       a      alfa
            L1 = Revolute ( 'a',L_1,'alpha',0,'d',L_3);
            L2 = Revolute ( 'a',L_2,'alpha',pi,'d',0);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            
            Robot = SerialLink([L1,L2,L3]); %,L4,L5,L6
            Robot.name = 'Robot Scara';
            
            T1=transl(Px0, Py0, Pz0)*trotx(0);
            T2=transl(Pxf, Pyf, Pzf)*trotx(0);
            
            t=[0:0.05:6]';
            q=Robot.jtraj(T1,T2,t,'ikine',@Robot.ikunc);
            
            
            % %yyaxis left
            % plot(q(1:2,:)*(180/pi))
            % ylabel('Grados')
            
            %yyaxis right
            plot(q(:,:));
            xlabel('Iteracion');
            ylabel('mm-Grados');
            legend({'Link 1','Link 2','Link 3'},'Location','southwest');
        end

        % Button pushed function: Graficar
        function Graficar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            cla (handles.axes1,'reset');
            Px0 = str2double(app.bt_Xo.Value);
            Py0 = str2double(app.bt_Yo.Value);
            Pz0 = str2double(app.bt_Zo.Value);
            
            Pxf = str2double(app.xf_3d.Value);
            Pyf = str2double(app.yf_3d.Value);
            Pzf = str2double(app.zf_3d.Value);
            
            n=150%str2double(app.bt_n.Value);        %Numero de pasos
            mag_v=str2double(app.bt_v.Value);      %Magnitud de v
            
            L_1 = 149; %a1
            L_2 = 142; %a2
            L_3 = 125; %d1
            
            %             teta       d       a      alfa
            L1 = Revolute ( 'a',L_1,'alpha',0,'d',L_3);
            L2 = Revolute ( 'a',L_2,'alpha',pi,'d',0);
            L3 = Prismatic( 'a',0,'alpha',0,'theta',0,'qlim',[0,25]);
            
            
            Robot = SerialLink([L1,L2,L3]); %,L4,L5,L6
            Robot.name = 'Robot Scara';
            P0=[Px0 Py0 Pz0];
            P3=[Pxf Pyf Pzf];
            T1=transl(Px0, Py0, Pz0)*trotx(0);
            T2=transl(Pxf, Pyf, Pzf)*trotx(0);
            ws=[-450,450,-450,450,0,200];
            
            d=sqrt((P3(1)-P0(1))^2+(P3(2)-P0(2))^2+(P3(3)-P0(3))^2);
            delt_t=d/(n*mag_v);
            t=d/(mag_v) ;   %Velocidad
            
            t=[0:delt_t:t]';
            q=Robot.jtraj(T1,T2,t,'ikine',@Robot.ikunc);
            Robot.plot(q,'workspace',ws,'noname')
        end

        % Button pushed function: Limpiar
        function Limpiar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Limpiar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             cla (handles.axes1,'reset');
             set(handles.A,'String',' ');
             set(handles.B,'String',' ');
             set(handles.C,'String',' ');
             set(handles.Theta1,'String',' ');
             set(handles.Theta2,'String',' ');
             set(handles.Theta3,'String',' ');
        end

        % Value changed function: Slider1
        function Slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            %Obtenemos el valor de teta 3 deseado
            t1 = get(handles.Slider1,'value');
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            set(handles.A,'String',t1);
            
            d1 = 125;
            a1 = 149;
            alp1 = 0;
            
            %Matriz de transformacion
            A01= [cosd(t1)   -sind(t1)*cosd(alp1)    sind(t1)*sind(alp1)     a1*cosd(t1);
                  sind(t1)    cosd(t1)*cosd(alp1)   -cosd(t1)*sind(alp1)     a1*sind(t1);
                   0             sind(alp1)             cosd(alp1)                 d1;
                   0                0                      0                       1];
            
            %Imprimir datos de la tabla 1
            set(handles.Tabla1,'data',A01);
            
            % Matriz global de transformacion
            try
            MATRIZ_TRANSFORMACION=A01*A12*A23;
            catch
                 warning('Error de dimensiones');
            end
            %Visualizacion
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
        end

        % Value changed function: Slider2
        function Slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            t2 = get(handles.Slider2,'value');
            set(handles.B,'String',t2);
            
            d2 = 70;
            a2 = 142;
            alp2 = 180;
            
            %Matriz de transformacion
            A12=[ cosd(t2)    -sind(t2)*cosd(alp2)     sind(t2)*sind(alp2)    a2*cosd(t2);
                  sind(t2)    cosd(t2)*cosd(alp2)     -cosd(t2)*sind(alp2)    a2*sind(t2);
                   0              sind(alp2)              cosd(alp2)              d2;
                   0                 0                       0                     1];
            
            
            %Imprimir datos de la tabla 2
            set(handles.Tabla2,'data',A12);
            
            % Matriz global de transformacion
            try
            MATRIZ_TRANSFORMACION=A01*A12*A23;
            catch
                warning('Error de dimensiones');
            end
            %Visualizacion
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
        end

        % Value changed function: Slider3
        function Slider3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global A01 A12 A23 MATRIZ_TRANSFORMACION
            d3 = get(handles.Slider3,'value');
            set(handles.C,'String',d3);
            
            t_3 = 0;
            a3 = 0;
            alp3 = 0;
            
            %Matriz de transformacion
            A23=[ cosd(t_3)   -sind(t_3)*cosd(alp3 )     sind(t_3)*sind(alp3 )    a3*cosd(t_3);
                  sind(t_3)    cosd(t_3)*cosd(alp3 )    -cosd(t_3)*sind(alp3 )    a3*sind(t_3);
                     0            sind(alp3 )               cosd(alp3 )                 d3;
                     0               0                      0                           1];
            
            %Imprimir datos de la tabla 3
            set(handles.Tabla3,'data',A23);
            
            % Matriz global de transformacion
            try
            MATRIZ_TRANSFORMACION=A01*A12*A23;
            catch
                warning('Error de dimensiones');
            end
            %Visualizacion
            set(handles.TablaT,'data',MATRIZ_TRANSFORMACION);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            cla (handles.axes1,'reset');
            vi_1 = str2double(app.Vel_in1.Value);   %Vel inicial
            vi_2 = str2double(app.Vel_in2.Value);
            vi_3 = str2double(app.Vel_in3.Value);
            
            vf_1 = str2double(app.Vel_fin1.Value);   %Vel Final
            vf_2 = str2double(app.Vel_fin2.Value);
            vf_3 = str2double(app.Vel_fin3.Value);
            
              app.n = str2double(app.bt_n_2.Value);        %Numero de pasos
              tf = str2double(app.tf.Value);      %Magnitud de v
              app.tfgraf=str2double(app.tf.Value);
            
                 
                            %Perfil de Velocidad Cuadratica
                            
                %Por Cinematica Inversa
                thi_1=0; %Theta iicial
                thf_1=str2double(app.Theta1ED.Value); %Theta Final
                
                cont=0;   %Contador
                
                thi_2=0 %Theta iicial
                thf_2=str2double(app.Theta2ED.Value); %Theta Final
              
                di_3=0 %D iicial
                df_3=str2double(app.D3E.Value);  %D Final
              
                %Eslavon 1
                %Simbolivos de elementos a buscar
                syms a3_1 a2_1 a1_1 a0_1 
                
                %Ecuaciones
                a0_1 =thi_1 
                a1_1 =vi_1
                q_1  =(a3_1*tf^3) + (a2_1*tf^2) + (a1_1*tf) + (a0_1)
                qp_1 =(3*a3_1*tf^2) + (2*a2_1*tf) + (a1_1)
                
                %Despejamos a2 y a3
                [a2_1,a3_1]=solve( [q_1;qp_1]==[thf_1;vf_1], [a2_1,a3_1] )
                
                a2_1=double(a2_1)
                a3_1=double(a3_1)
                
                %Eslavon 2
                %Simbolivos de elementos a buscar
                syms a3_2 a2_2 a1_2 a0_2 
                
                %Ecuaciones
                a0_2 =thi_2 
                a1_2 =vi_2
                q_2  =(a3_2*tf^3) + (a2_2*tf^2) + (a1_2*tf) + (a0_2)
                qp_2 =(3*a3_2*tf^2) + (2*a2_2*tf) + (a1_2)
                
                %Despejamos a2 y a3
                [a2_2,a3_2]=solve( [q_2;qp_2]==[thf_2;vf_2], [a2_2,a3_2] )
                
                a2_2=double(a2_2)
                a3_2=double(a3_2)
                
                
                %Eslavon 3
                %Simbolivos de elementos a buscar
                syms a3_3 a2_3 a1_3 a0_3 
                
                %Ecuaciones
                a0_3 =di_3 
                a1_3 =vi_3
                q_3  =(a3_3*tf^3) + (a2_3*tf^2) + (a1_3*tf) + (a0_3)
                qp_3 =(3*a3_3*tf^2) + (2*a2_3*tf) + (a1_3)
                
                %Despejamos a2 y a3
                [a2_3,a3_3]=solve( [q_3;qp_3]==[df_3;vf_3], [a2_3,a3_3] )
                
                a2_3=double(a2_3)
                a3_3=double(a3_3)
                
                %Ya con los valores a de cada eslavon iteramos 
                %global vect vectv vecta tf n
                
               app.vect=zeros(app.n,3)
               app.vectv=zeros(app.n,3)
               app.vecta=zeros(app.n,3)
                
                for t=0:tf/app.n:tf
                    cont=cont+1;
                    
                    %Eslabon 1
                    theta_1 =  a3_1*t^3 +  a2_1*t^2 + a1_1*t + a0_1
                    v_1     =3*a3_1*t^2 +2*a2_1*t   + a1_1
                    a_1     =6*a3_1*t   +  a2_1
                    app.vect(cont,1)=theta_1
                    app.vectv(cont,1)=v_1
                    app.vecta(cont,1)=a_1
                    
                    %Eslabon 2
                    theta_2 =  a3_2*t^3 +  a2_2*t^2 + a1_2*t + a0_2
                    v_2     =3*a3_2*t^2 +2*a2_2*t   + a1_2
                    a_2     =6*a3_2*t   +  a2_2
                    app.vect(cont,2)=theta_2
                    app.vectv(cont,2)=v_2
                    app.vecta(cont,2)=a_2
                    
                    %Eslabon 3
                    Dis_3   =  a3_3*t^3 +  a2_3*t^2 + a1_3*t + a0_3
                    v_3     =3*a3_3*t^2 +2*a2_3*t   + a1_3
                    a_3     =6*a3_3*t   +  a2_3
                    app.vect(cont,3)=Dis_3
                    app.vectv(cont,3)=v_3
                    app.vecta(cont,3)=a_3
                end
              
                
        end

        % Button pushed function: segButton
        function segButtonPushed(app, event)
            
             x=0: app.tfgraf/app.n: app.tfgraf
                
                plot(x,app.vectv)
                legend('Theta1','Theta2','D3');
                xlabel('n')
                ylabel('mm/n')
        end

        % Button pushed function: PosicionButton_2
        function PosicionButton_2Pushed(app, event)
          
                x=0: app.tfgraf/app.n: app.tfgraf
                
                plot(x,app.vect)
                legend('Theta1','Theta2','D3');
                xlabel('n')
                ylabel('mm')
        end

        % Button pushed function: AceleracionButton
        function AceleracionButtonPushed(app, event)
     
               
                 x=0: app.tfgraf/app.n: app.tfgraf
                plot(x,app.vecta)
                legend('Theta1','Theta2','D3');
                xlabel('n')
                ylabel('mm/n^2')
        end

        % Button pushed function: InterfazCartesianoButton
        function InterfazCartesianoButtonPushed(app, event)
            APP_Cartesiano
            delete(app)
        end

        % Button pushed function: MovimientoButton
        function MovimientoButtonPushed(app, event)
          %CONEXION plot(app.UIAxes,t,fun)
disp('Program started');
    % sim=remApi('remoteApi','extApi.h'); % using the header (requires a compiler)
    sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
    sim.simxFinish(-1); % just in case, close all opened connections
    clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);

    if (clientID>-1);
    disp('conectado');
    
    %Elementos del robot
    %Scara
    [returnCode,art_1]=sim.simxGetObjectHandle(clientID,'Hombro',sim.simx_opmode_blocking);
    [returnCode,art_2]=sim.simxGetObjectHandle(clientID,'Codo',sim.simx_opmode_blocking);
    [returnCode,art_3]=sim.simxGetObjectHandle(clientID,'Muneca',sim.simx_opmode_blocking);
    [returnCode,j1]=sim.simxGetObjectHandle(clientID,'ROBOTIQ_85_active1',sim.simx_opmode_blocking);
    [returnCode,j2]=sim.simxGetObjectHandle(clientID,'ROBOTIQ_85_active2',sim.simx_opmode_blocking);
    [returnCode,o1]=sim.simxGetObjectHandle(clientID,'Cuboid0',sim.simx_opmode_blocking);
    [returnCode,o2]=sim.simxGetObjectHandle(clientID,'Cuboid1',sim.simx_opmode_blocking);
     [returnCode,o3]=sim.simxGetObjectHandle(clientID,'Cuboid2',sim.simx_opmode_blocking);
    [returnCode,o4]=sim.simxGetObjectHandle(clientID,'Cuboid3',sim.simx_opmode_blocking);
     [returnCode,o5]=sim.simxGetObjectHandle(clientID,'Cuboid4',sim.simx_opmode_blocking);


    %aviso de conexion establecida
    sim.simxAddStatusbarMessage(clientID,'COmunicacion con MATLAB iniciada',sim.simx_opmode_blocking);
    display('Comunicacion con cop iniciada');
    
    %Movimientos de prueba
    %Scara

%%PRIMER CUBO 
vect=movimiento(0,-180,0,45,0,0)


tf=5;%Tiempo Final
contador=0;
n=100;
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');
 
for t=0:tf/n:tf
    contador=contador+1;
 
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o1,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o1,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,vect(contador,3),sim.simx_opmode_oneshot); %metros
[returnCode]=sim.simxSetObjectPosition(clientID, o1,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
 end
gripper(clientID,0,j1,j2); %openGripper
[returnCode]=sim.simxSetObjectPosition(clientID, o1,art_3,[3.8E-3 -5.906E-4 -0.125] , sim.simx_opmode_oneshot);
pause(0.5);

%%SEGUNDO CUBO IDA

contador=0;
vect=movimiento(-180,-90,45,0,0,-0.025)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');

for t=0:tf/n:tf
    contador=contador+1;
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
end
 [returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,-0.025,sim.simx_opmode_oneshot); %metros
gripper(clientID,1,j1,j2); 
%%SEGUNDO CUBO VUELTA
vect=movimiento(-90,-180,0,45,-0.025,0)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros')

contador=0;

for t=0:tf/n:tf
    contador=contador+1;
 
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o2,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o2,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,vect(contador,3),sim.simx_opmode_oneshot); %metros
[returnCode]=sim.simxSetObjectPosition(clientID, o2,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
 end
gripper(clientID,0,j1,j2); %openGripper
[returnCode]=sim.simxSetObjectPosition(clientID, o2,art_3,[3.8E-3 -5.906E-4 -0.1125] , sim.simx_opmode_oneshot);
pause(0.5)

%%TERCER CUBO IDA
contador=0;

vect=movimiento(-180,-45,45,-20,0,-0.025)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');

for t=0:tf/n:tf
    contador=contador+1;
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
end
 [returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,-0.025,sim.simx_opmode_oneshot); %metros
gripper(clientID,1,j1,j2); 

%%TERCER CUBO VUELTA
vect=movimiento(-45,-180,-20,45,-0.025,0)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');


contador=0;

for t=0:tf/n:tf
    contador=contador+1;
 
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o3,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o3,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,vect(contador,3),sim.simx_opmode_oneshot); %metros
[returnCode]=sim.simxSetObjectPosition(clientID, o3,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
 end
gripper(clientID,0,j1,j2); %openGripper
[returnCode]=sim.simxSetObjectPosition(clientID, o3,art_3,[3.8E-3 -5.906E-4 -0.1] , sim.simx_opmode_oneshot);
pause(0.5) 

    %%CUARTO CUBO IDA
contador=0;

vect=movimiento(-180,0,45,-35,0,-0.025)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');
for t=0:tf/n:tf
    contador=contador+1;
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
end
 [returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,-0.025,sim.simx_opmode_oneshot); %metros
gripper(clientID,1,j1,j2); 

%%CUARTO CUBO VUELTA
vect=movimiento(0,-180,-35,55,-0.025,0)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');
  
contador=0;

for t=0:tf/n:tf
    contador=contador+1;
 
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o4,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o4,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,vect(contador,3),sim.simx_opmode_oneshot); %metros
[returnCode]=sim.simxSetObjectPosition(clientID, o4,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
 end
gripper(clientID,0,j1,j2); %openGripper
[returnCode]=sim.simxSetObjectPosition(clientID, o4,art_3,[3.8E-3 -5.906E-4 -0.125] , sim.simx_opmode_oneshot);
pause(0.5) 

%%QUINTO CUBO IDA
contador=0;

vect=movimiento(-180,0,55,65,0,-0.025)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');

for t=0:tf/n:tf
    contador=contador+1;
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
 pause(0.01);
end
 [returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,-0.025,sim.simx_opmode_oneshot); %metros
gripper(clientID,1,j1,j2); 


%%QUINTO CUBO VUELTA
vect=movimiento(0,-180,65,55,-0.025,0)
                x=0:tf/n:tf;
                yyaxis left
                plot(app.axes1,x,vect(:,1:2))
                legend('Theta1','Theta2','D3');
                xlabel('n');
                ylabel('Grados');
                yyaxis right
                plot(app.axes1,x,vect(:,3))
                ylabel('Metros');
              

contador=0;

for t=0:tf/n:tf
    contador=contador+1;
 
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_1,vect(contador,1)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o5,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_2,vect(contador,2)*pi/180,sim.simx_opmode_oneshot); %radianes
[returnCode]=sim.simxSetObjectPosition(clientID, o5,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
[returnCode]=sim.simxSetJointTargetPosition(clientID,art_3,vect(contador,3),sim.simx_opmode_oneshot); %metros
[returnCode]=sim.simxSetObjectPosition(clientID, o5,art_3,[3.8E-3 -5.906E-4 -0.08109] , sim.simx_opmode_oneshot);
 pause(0.01);
 end
gripper(clientID,0,j1,j2); %openGripper
[returnCode]=sim.simxSetObjectPosition(clientID, o5,art_3,[3.8E-3 -5.906E-4 -0.1125] , sim.simx_opmode_oneshot);
pause(0.5) 

    end 
    %desconectar
     sim.simxAddStatusbarMessage(clientID,'Hello CoppeliaSim!',sim.simx_opmode_oneshot);
        sim.simxGetPingTime(clientID);  
        sim.simxFinish(clientID);
        display('conexion finalizada');
        sim.delete();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'MANIPULADOR SCARA';
            app.Aviso = uieditfield(app.figure1, 'text');
            app.Aviso.Tag = 'Aviso';
            app.TabGroup = uitabgroup(app.figure1);
            app.CinamicaDirectaTab = uitab(app.TabGroup);
            app.CinamicaDirectaTab.Title = 'Cinamica Directa';
            app.SlidersPanel = uipanel(app.CinamicaDirectaTab);
            app.SlidersPanel.Title = 'Sliders';
            app.Slider1 = uislider(app.SlidersPanel);
            app.Slider1.Limits = [-360 360];
            app.Slider1.MajorTicks = [];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1_Callback, true);
            app.Slider1.Tag = 'Slider1';
            app.Teta1 = uilabel(app.SlidersPanel);
            app.Teta1.Tag = 'Teta1';
            app.Teta1.Text = 'Teta1';
            app.text3 = uilabel(app.SlidersPanel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Teta2';
            app.Slider2 = uislider(app.SlidersPanel);
            app.Slider2.Limits = [-120 120];
            app.Slider2.MajorTicks = [];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2_Callback, true);
            app.Slider2.Tag = 'Slider2';
            app.Slider3 = uislider(app.SlidersPanel);
            app.Slider3.Limits = [0 70];
            app.Slider3.MajorTicks = [];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3_Callback, true);
            app.Slider3.Tag = 'Slider3';
            app.text4 = uilabel(app.SlidersPanel);
            app.text4.Tag = 'text4';
            app.text4.Text = 'D';
            app.A = uieditfield(app.SlidersPanel, 'text');
            app.A.Tag = 'A';
            app.B = uieditfield(app.SlidersPanel, 'text');
            app.B.Tag = 'B';
            app.C = uieditfield(app.SlidersPanel, 'text');
            app.C.Tag = 'C';
            app.Boton3 = uibutton(app.SlidersPanel, 'push');
            app.Boton3.ButtonPushedFcn = createCallbackFcn(app, @Boton3_Callback, true);
            app.Boton3.Tag = 'Boton3';
            app.Boton3.Text = 'Graficar';
            app.text8 = uilabel(app.SlidersPanel);
            app.text8.Tag = 'text8';
            app.text8.Text = '-360';
            app.text9 = uilabel(app.SlidersPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = '360';
            app.text10 = uilabel(app.SlidersPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '-120';
            app.text11 = uilabel(app.SlidersPanel);
            app.text11.Tag = 'text11';
            app.text11.Text = '120';
            app.text12 = uilabel(app.SlidersPanel);
            app.text12.Tag = 'text12';
            app.text12.Text = '0';
            app.text13 = uilabel(app.SlidersPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = '70';
            app.ManualPanel = uipanel(app.CinamicaDirectaTab);
            app.ManualPanel.Title = 'Manual';
            app.Boton1 = uibutton(app.ManualPanel, 'push');
            app.Boton1.ButtonPushedFcn = createCallbackFcn(app, @Boton1_Callback, true);
            app.Boton1.Tag = 'Boton1';
            app.Boton1.Text = 'Graficar';
            app.Theta1 = uieditfield(app.ManualPanel, 'text');
            app.Theta1.Tag = 'Theta1';
            app.Theta2 = uieditfield(app.ManualPanel, 'text');
            app.Theta2.Tag = 'Theta2';
            app.Theta3 = uieditfield(app.ManualPanel, 'text');
            app.Theta3.Tag = 'Theta3';
            app.text14 = uilabel(app.ManualPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Digite los grados en Teta1';
            app.text15 = uilabel(app.ManualPanel);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Digite los grados en Teta2';
            app.text16 = uilabel(app.ManualPanel);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Digite la distancia del gripper';
            app.CinematicainversaTab = uitab(app.TabGroup);
            app.CinematicainversaTab.Title = 'Cinematica inversa';
            app.PosicionEfectorFinalPanel = uipanel(app.CinematicainversaTab);
            app.PosicionEfectorFinalPanel.Title = 'Posicion Efector Final';
            app.EfectorX = uieditfield(app.PosicionEfectorFinalPanel, 'text');
            app.EfectorX.Tag = 'EfectorX';
            app.EfectorY = uieditfield(app.PosicionEfectorFinalPanel, 'text');
            app.EfectorY.Tag = 'EfectorY';
            app.EfectorZ = uieditfield(app.PosicionEfectorFinalPanel, 'text');
            app.EfectorZ.Tag = 'EfectorZ';
            app.text22 = uilabel(app.PosicionEfectorFinalPanel);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Digite posicion en X del efector final';
            app.text23 = uilabel(app.PosicionEfectorFinalPanel);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Digite posicion en Y del efector final';
            app.text24 = uilabel(app.PosicionEfectorFinalPanel);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Digite posicion en Z del efector final';
            app.AngulosObtenidosPanel = uipanel(app.CinematicainversaTab);
            app.AngulosObtenidosPanel.Title = 'Angulos Obtenidos';
            app.Theta1ED = uieditfield(app.AngulosObtenidosPanel, 'text');
            app.Theta1ED.Tag = 'Theta1ED';
            app.Theta2ED = uieditfield(app.AngulosObtenidosPanel, 'text');
            app.Theta2ED.Tag = 'Theta2ED';
            app.text25 = uilabel(app.AngulosObtenidosPanel);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Theta-2ED';
            app.text26 = uilabel(app.AngulosObtenidosPanel);
            app.text26.Tag = 'text26';
            app.text26.Text = 'D3-ED-EU';
            app.D3E = uieditfield(app.AngulosObtenidosPanel, 'text');
            app.D3E.Tag = 'D3E';
            app.text27 = uilabel(app.AngulosObtenidosPanel);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Theta1-ED';
            app.Theta1EU = uieditfield(app.AngulosObtenidosPanel, 'text');
            app.Theta1EU.Tag = 'Theta1EU';
            app.Theta2EU = uieditfield(app.AngulosObtenidosPanel, 'text');
            app.Theta2EU.Tag = 'Theta2EU';
            app.text28 = uilabel(app.AngulosObtenidosPanel);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Theta2-EU';
            app.text30 = uilabel(app.AngulosObtenidosPanel);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Theta1-EU';
            app.BotonEF = uibutton(app.CinematicainversaTab, 'push');
            app.BotonEF.ButtonPushedFcn = createCallbackFcn(app, @BotonEF_Callback, true);
            app.BotonEF.Tag = 'BotonEF';
            app.BotonEF.Text = 'Cargar Texto Efector Codo Abajo';
            app.BotonEU = uibutton(app.CinematicainversaTab, 'push');
            app.BotonEU.ButtonPushedFcn = createCallbackFcn(app, @BotonEU_Callback, true);
            app.BotonEU.Tag = 'BotonEU';
            app.BotonEU.Text = 'Cargar Texto Efector Codo Arriba';
            app.Grafica3DTab = uitab(app.TabGroup);
            app.Grafica3DTab.Title = 'Grafica 3D';
            app.PosicionPanel = uipanel(app.Grafica3DTab);
            app.PosicionPanel.Title = 'Posicion';
            app.bt_Xo = uieditfield(app.PosicionPanel, 'text');
            app.bt_Xo.Tag = 'bt_Xo';
            app.bt_Xo.Value = '261';
            app.bt_Zo = uieditfield(app.PosicionPanel, 'text');
            app.bt_Zo.Tag = 'bt_Zo';
            app.bt_Zo.Value = '125';
            app.bt_Yo = uieditfield(app.PosicionPanel, 'text');
            app.bt_Yo.Tag = 'bt_Yo';
            app.bt_Yo.Value = '0';
            app.text31 = uilabel(app.PosicionPanel);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Posicion Inicial Mov 3D';
            app.text31_2 = uilabel(app.PosicionPanel);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'Posicion Final Mov 3D';
            app.xf_3d = uieditfield(app.PosicionPanel, 'text');
            app.xf_3d.Tag = 'edit26';
            app.xf_3d.Value = '0';
            app.zf_3d = uieditfield(app.PosicionPanel, 'text');
            app.zf_3d.Tag = 'edit27';
            app.zf_3d.Value = '30';
            app.yf_3d = uieditfield(app.PosicionPanel, 'text');
            app.yf_3d.Tag = 'edit28';
            app.yf_3d.Value = '261';
            app.XfLabel = uilabel(app.PosicionPanel);
            app.XfLabel.Text = 'Xf';
            app.YfLabel = uilabel(app.PosicionPanel);
            app.YfLabel.Text = 'Yf';
            app.ZfLabel = uilabel(app.PosicionPanel);
            app.ZfLabel.Text = 'Zf';
            app.XoLabel = uilabel(app.PosicionPanel);
            app.XoLabel.Text = 'Xo';
            app.YoLabel = uilabel(app.PosicionPanel);
            app.YoLabel.Text = 'Yo';
            app.ZoLabel = uilabel(app.PosicionPanel);
            app.ZoLabel.Text = 'Zo';
            app.Graf_Links = uibutton(app.Grafica3DTab, 'push');
            app.Graf_Links.ButtonPushedFcn = createCallbackFcn(app, @Graf_Links_Callback, true);
            app.Graf_Links.Tag = 'Graf_Links';
            app.Graf_Links.Text = 'Graficar Links';
            app.Graficar = uibutton(app.Grafica3DTab, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @Graficar_Callback, true);
            app.Graficar.Tag = 'Graficar';
            app.Graficar.Text = 'Graficar 3D';
            app.ConfiguracionesPanel = uipanel(app.Grafica3DTab);
            app.ConfiguracionesPanel.Title = 'Configuraciones';
            app.bt_n = uieditfield(app.ConfiguracionesPanel, 'text');
            app.bt_n.Tag = 'bt_n';
            app.bt_n.Value = '150';
            app.bt_v = uieditfield(app.ConfiguracionesPanel, 'text');
            app.bt_v.Tag = 'bt_v';
            app.bt_v.Value = '0.1';
            app.PasosLabel = uilabel(app.ConfiguracionesPanel);
            app.PasosLabel.Text = '# Pasos';
            app.VelMovimientoLabel = uilabel(app.ConfiguracionesPanel);
            app.VelMovimientoLabel.Text = 'Vel Movimiento';
            app.TablaDH = uitab(app.TabGroup);
            app.TablaDH.Title = 'Tabla DH';
            app.TabGroup2 = uitabgroup(app.TablaDH);
            app.A1Tab = uitab(app.TabGroup2);
            app.A1Tab.Title = 'A1';
            app.Tabla1 = uitable(app.A1Tab);
            app.Tabla1.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.Tabla1.RowName = {'1'; '2'; '3'; ''};
            app.Tabla1.ColumnEditable = [false false false false];
            app.Tabla1.Tag = 'Tabla1';
            app.text17 = uilabel(app.A1Tab);
            app.text17.Tag = 'text17';
            app.text17.Text = 'A01';
            app.A2Tab = uitab(app.TabGroup2);
            app.A2Tab.Title = 'A2';
            app.Tabla2 = uitable(app.A2Tab);
            app.Tabla2.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.Tabla2.RowName = {'1'; '2'; '3'; ''};
            app.Tabla2.ColumnEditable = [false false false false];
            app.Tabla2.Tag = 'Tabla2';
            app.text18 = uilabel(app.A2Tab);
            app.text18.Tag = 'text18';
            app.text18.Text = 'A12';
            app.A2Tab_2 = uitab(app.TabGroup2);
            app.A2Tab_2.Title = 'A2';
            app.Tabla3 = uitable(app.A2Tab_2);
            app.Tabla3.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.Tabla3.RowName = {'1'; '2'; '3'; ''};
            app.Tabla3.ColumnEditable = [false false false false];
            app.Tabla3.Tag = 'Tabla3';
            app.text19 = uilabel(app.A2Tab_2);
            app.text19.Tag = 'text19';
            app.text19.Text = 'A23';
            app.A3Tab = uitab(app.TabGroup2);
            app.A3Tab.Title = 'A3';
            app.TablaT = uitable(app.A3Tab);
            app.TablaT.ColumnName = {'R1'; 'R2'; 'R3'; 'P'};
            app.TablaT.RowName = {'1'; '2'; '3'; ''};
            app.TablaT.ColumnEditable = [false false false false];
            app.TablaT.Tag = 'TablaT';
            app.text20 = uilabel(app.A3Tab);
            app.text20.Tag = 'text20';
            app.text20.Text = 'A T';
            app.PerfilCuadraticoTab = uitab(app.TabGroup);
            app.PerfilCuadraticoTab.Title = 'Perfil Cuadratico';
            app.CalcularButton = uibutton(app.PerfilCuadraticoTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.VelocidadPanel = uipanel(app.PerfilCuadraticoTab);
            app.VelocidadPanel.Title = 'Velocidad';
            app.Vel_in1 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_in1.Tag = 'bt_Xo';
            app.Vel_in1.Value = '261';
            app.Vel_in3 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_in3.Tag = 'bt_Zo';
            app.Vel_in3.Value = '125';
            app.Vel_in2 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_in2.Tag = 'bt_Yo';
            app.Vel_in2.Value = '0';
            app.text31_3 = uilabel(app.VelocidadPanel);
            app.text31_3.Tag = 'text31';
            app.text31_3.Text = 'Inicial';
            app.text31_4 = uilabel(app.VelocidadPanel);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'Final';
            app.Vel_fin1 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_fin1.Tag = 'edit26';
            app.Vel_fin1.Value = '0';
            app.Vel_fin3 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_fin3.Tag = 'edit27';
            app.Vel_fin3.Value = '30';
            app.Vel_fin2 = uieditfield(app.VelocidadPanel, 'text');
            app.Vel_fin2.Tag = 'edit28';
            app.Vel_fin2.Value = '261';
            app.VeFi1Label = uilabel(app.VelocidadPanel);
            app.VeFi1Label.Text = 'VeFi1';
            app.VeFi2Label = uilabel(app.VelocidadPanel);
            app.VeFi2Label.Text = 'VeFi2';
            app.VeFi3Label = uilabel(app.VelocidadPanel);
            app.VeFi3Label.Text = 'VeFi3';
            app.VeIn1Label = uilabel(app.VelocidadPanel);
            app.VeIn1Label.Text = 'VeIn1';
            app.VeIn2Label = uilabel(app.VelocidadPanel);
            app.VeIn2Label.Text = 'VeIn2';
            app.VeIn3Label = uilabel(app.VelocidadPanel);
            app.VeIn3Label.Text = 'VeIn3';
            app.ConfiguracionesPanel_2 = uipanel(app.PerfilCuadraticoTab);
            app.ConfiguracionesPanel_2.Title = 'Configuraciones';
            app.bt_n_2 = uieditfield(app.ConfiguracionesPanel_2, 'text');
            app.bt_n_2.Tag = 'bt_n';
            app.bt_n_2.Value = '150';
            app.tf = uieditfield(app.ConfiguracionesPanel_2, 'text');
            app.tf.Tag = 'bt_v';
            app.tf.Value = '20';
            app.PasosLabel_2 = uilabel(app.ConfiguracionesPanel_2);
            app.PasosLabel_2.Text = '# Pasos';
            app.TiempoFinalLabel = uilabel(app.ConfiguracionesPanel_2);
            app.TiempoFinalLabel.Text = 'Tiempo Final';
            app.PorfavorprimerorealiceCinematicaInversaLabel = uilabel(app.PerfilCuadraticoTab);
            app.PorfavorprimerorealiceCinematicaInversaLabel.Text = 'Por favor primero realice Cinematica Inversa';
            app.segButton = uibutton(app.PerfilCuadraticoTab, 'push');
            app.segButton.ButtonPushedFcn = createCallbackFcn(app, @segButtonPushed, true);
            app.segButton.Text = 'seg';
            app.AceleracionButton = uibutton(app.PerfilCuadraticoTab, 'push');
            app.AceleracionButton.ButtonPushedFcn = createCallbackFcn(app, @AceleracionButtonPushed, true);
            app.AceleracionButton.Text = 'Aceleracion';
            app.PosicionButton_2 = uibutton(app.PerfilCuadraticoTab, 'push');
            app.PosicionButton_2.ButtonPushedFcn = createCallbackFcn(app, @PosicionButton_2Pushed, true);
            app.PosicionButton_2.Text = 'Posicion';
            app.PosicionActualPanel = uipanel(app.figure1);
            app.PosicionActualPanel.Title = 'Posicion Actual';
            app.PosX = uieditfield(app.PosicionActualPanel, 'text');
            app.PosX.Tag = 'PosX';
            app.text5 = uilabel(app.PosicionActualPanel);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Posicion Eje X';
            app.PosY = uieditfield(app.PosicionActualPanel, 'text');
            app.PosY.Tag = 'PosY';
            app.text6 = uilabel(app.PosicionActualPanel);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Posicion Eje Y';
            app.text7 = uilabel(app.PosicionActualPanel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Posicion Eje Z';
            app.PosZ = uieditfield(app.PosicionActualPanel, 'text');
            app.PosZ.Tag = 'PosZ';
            app.Limpiar = uibutton(app.PosicionActualPanel, 'push');
            app.Limpiar.ButtonPushedFcn = createCallbackFcn(app, @Limpiar_Callback, true);
            app.Limpiar.Tag = 'Limpiar';
            app.Limpiar.Text = 'Limpiar';
            app.InterfazCartesianoButton = uibutton(app.figure1, 'push');
            app.InterfazCartesianoButton.ButtonPushedFcn = createCallbackFcn(app, @InterfazCartesianoButtonPushed, true);
            app.InterfazCartesianoButton.Text = 'Interfaz Cartesiano';
            app.MovimientoButton = uibutton(app.figure1, 'push');
            app.MovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @MovimientoButtonPushed, true);
            app.MovimientoButton.Text = 'Movimiento';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_SCARA(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end